classdef appforPool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        WinchControlTab               matlab.ui.container.Tab
        Winch1SpeedrpmSliderLabel     matlab.ui.control.Label
        Winch1SpeedrpmSlider          matlab.ui.control.Slider
        Winch1SpeedrpmEditFieldLabel  matlab.ui.control.Label
        Winch1SpeedrpmEditField       matlab.ui.control.NumericEditField
        EngageWinchesButton           matlab.ui.control.StateButton
        StartWinch12Button            matlab.ui.control.StateButton
        SpoolingDirectionSwitchLabel  matlab.ui.control.Label
        SpoolingDirectionSwitch       matlab.ui.control.Switch
        Winch1AmplituderpmEditFieldLabel  matlab.ui.control.Label
        Winch1AmplituderpmEditField   matlab.ui.control.NumericEditField
        Winch1PeriodsEditFieldLabel   matlab.ui.control.Label
        Winch1PeriodsEditField        matlab.ui.control.NumericEditField
        BrakingtimesEditFieldLabel    matlab.ui.control.Label
        BrakingtimesEditField         matlab.ui.control.NumericEditField
        SpoolingProfileButtonGroup    matlab.ui.container.ButtonGroup
        ConstantButton                matlab.ui.control.ToggleButton
        SineButton                    matlab.ui.control.ToggleButton
        TriangleButton                matlab.ui.control.ToggleButton
        Winch2SpeedrpmSliderLabel     matlab.ui.control.Label
        Winch2SpeedrpmSlider          matlab.ui.control.Slider
        SpoolingProfileButtonGroup_2  matlab.ui.container.ButtonGroup
        ConstantButton_2              matlab.ui.control.ToggleButton
        SineButton_2                  matlab.ui.control.ToggleButton
        TriangleButton_2              matlab.ui.control.ToggleButton
        Winch2SpeedrpmEditFieldLabel  matlab.ui.control.Label
        Winch2SpeedrpmEditField       matlab.ui.control.NumericEditField
        Winch2AmplituderpmEditFieldLabel  matlab.ui.control.Label
        Winch2AmplituderpmEditField   matlab.ui.control.NumericEditField
        Winch2PeriodsEditFieldLabel   matlab.ui.control.Label
        Winch2PeriodsEditField        matlab.ui.control.NumericEditField
        DualWinchModeSwitch           matlab.ui.control.Switch
        Winch1Label                   matlab.ui.control.Label
        Winch2Label                   matlab.ui.control.Label
        StartWinch2Button             matlab.ui.control.StateButton
        Lamp                          matlab.ui.control.Lamp
        Lamp_2                        matlab.ui.control.Lamp
        Lamp_3                        matlab.ui.control.Lamp
        SettingsforbothwinchesLabel   matlab.ui.control.Label
        TestStartStopLabel            matlab.ui.control.Label
        CalibrationLabel              matlab.ui.control.Label
        LASCalibrationButton          matlab.ui.control.StateButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Turn all amplitudes and speeds to zero
            set_param([bdroot,'/ConstantSP'],'Value','0')
            set_param([bdroot,'/ConstantSP2'],'Value','0')
            set_param([bdroot,'/TriGain'],'Gain','0')
            set_param([bdroot,'/TriGain2'],'Gain','0')
            set_param([bdroot,'/SineWave'],'Amplitude','0')
            set_param([bdroot,'/SineWave2'],'Amplitude','0')
            set_param([bdroot,'/CouplingOnOff'],'Gain','0')
            app.Winch1SpeedrpmEditField.Value = 0;
            app.Winch2SpeedrpmEditField.Value = 0;

            
            % Set periods and rampdown time to 1 second
            set_param([bdroot,'/StopTime'],'Value','0')
            set_param([bdroot,'/SineWave'],'Frequency','2*pi')
            set_param([bdroot,'/SineWave2'],'Frequency','2*pi')
            set_param([bdroot,'/TriangleWave'],'Freq','1')
            set_param([bdroot,'/TriangleWave2'],'Freq','1')
            
            % Set winches as disabled and powered off
            set_param([bdroot,'/WinchPower'],'Value','0')
            set_param([bdroot,'/Winch2Power'],'Value','0')
            set_param([bdroot,'/Winch1Enable'],'Value','0')
            set_param([bdroot,'/Winch2Enable'],'Value','0')
            
            % Set winches to spool-out mode
            set_param([bdroot,'/SpoolingDirection'],'Value','1')
        end

        % Value changing function: Winch1SpeedrpmSlider
        function Winch1SpeedrpmSliderValueChanging(app, event)
            changingValue = event.Value;
            set_param([bdroot,'/ConstantSP'],'Value',num2str(changingValue))
            app.Winch1SpeedrpmEditField.Value = changingValue;
        end

        % Value changed function: Winch1SpeedrpmEditField
        function Winch1SpeedrpmEditFieldValueChanged(app, event)
            value = app.Winch1SpeedrpmEditField.Value;
            set_param([bdroot,'/ConstantSP'],'Value',num2str(value))
            app.Winch1SpeedrpmSlider.Value = value;
        end

        % Value changed function: StartWinch12Button
        function StartWinch12ButtonValueChanged(app, event)
            value = app.StartWinch12Button.Value;
            set_param([bdroot,'/WinchPower'],'Value',num2str(value))
            app.Lamp_2.Color = [1-value,value,0];
            
            isCoupled = app.DualWinchModeSwitch.Value;
            if strcmp(isCoupled,'Coupled (W2 = W1 + W2)')
                app.Lamp_3.Color = [1-value,value,0];
            end
        end

        % Value changed function: EngageWinchesButton
        function EngageWinchesButtonValueChanged(app, event)
            value = app.EngageWinchesButton.Value;
            set_param([bdroot,'/Winch1Enable'],'Value',num2str(value))
            set_param([bdroot,'/Winch2Enable'],'Value',num2str(value))
            app.Lamp.Color = [1-value,value,0];
            
            % Initial Parameters
            
            % Winch Power
            set_param([bdroot,'/WinchPower'],'Value','0')
            app.StartWinch12Button.Value = 0;
            
            % Stopping Time
            set_param([bdroot,'/StopTime'],'Value','1')
            app.BrakingtimesEditField.Value = 1;         
            
        end

        % Value changed function: SpoolingDirectionSwitch
        function SpoolingDirectionSwitchValueChanged(app, event)
            direction = app.SpoolingDirectionSwitch.Value;
            if strcmp(direction,'In')
                value = 0;
            else
                value = 1;
            end
            set_param([bdroot,'/SpoolingDirection'],'Value',num2str(value))
        end

        % Selection changed function: SpoolingProfileButtonGroup
        function SpoolingProfileButtonGroupSelectionChanged(app, event)
            selectedButton = app.SpoolingProfileButtonGroup.SelectedObject;
            
            if strcmp(selectedButton.Text,'Constant') % Zero sine and triangle
                set_param([bdroot,'/SineOnOff'],'Gain','0')
                set_param([bdroot,'/TriOnOff'],'Gain','0')
                
            elseif strcmp(selectedButton.Text,'Sine') % Zero triangle
                set_param([bdroot,'/SineOnOff'],'Gain','1')
                set_param([bdroot,'/TriOnOff'],'Gain','0')
                
            else                                      % Zero sine
                set_param([bdroot,'/SineOnOff'],'Gain','0')
                set_param([bdroot,'/TriOnOff'],'Gain','1')
                
            end            
        end

        % Value changed function: Winch1PeriodsEditField
        function Winch1PeriodsEditFieldValueChanged(app, event)
            value = app.Winch1PeriodsEditField.Value;
            set_param([bdroot,'/SineWave'],'Frequency',num2str(2*pi/value))
            set_param([bdroot,'/TriangleWave'],'Freq',num2str(1/value))
        end

        % Value changed function: Winch1AmplituderpmEditField
        function Winch1AmplituderpmEditFieldValueChanged(app, event)
            value = app.Winch1AmplituderpmEditField.Value;
            set_param([bdroot,'/SineWave'],'Amplitude',num2str(value))
            set_param([bdroot,'/TriGain'],'Gain',num2str(value))
        end

        % Value changed function: BrakingtimesEditField
        function BrakingtimesEditFieldValueChanged(app, event)
            value = app.BrakingtimesEditField.Value;
            set_param([bdroot,'/StopTime'],'Value',num2str(value))
            app.RampdowntimesEditField_2.Value = value;
        end

        % Callback function
        function WinchEnableButton_2ValueChanged(app, event)
            value = app.WinchEnableButton_2.Value;
            set_param([bdroot,'/Winch2Enable'],'Value',num2str(value))
            
            % Initial Parameters
            
            % Winch Power
            set_param([bdroot,'/Winch2Power'],'Value','0')
            app.StartWinch12Button.Value = 0;
            
            % Stopping Time %%%%%%%
            set_param([bdroot,'/StopTime'],'Value','1')
            app.BrakingtimesEditField.Value = 1;         
            
        end

        % Callback function
        function WinchONButton_2ValueChanged(app, event)
            value = app.WinchONButton_2.Value;
            set_param([bdroot,'/Winch2Power'],'Value',num2str(value))
        end

        % Callback function
        function RampdowntimesEditField_2ValueChanged(app, event)
            value = app.RampdowntimesEditField_2.Value;
            set_param([bdroot,'/StopTime'],'Value',num2str(value))
            app.BrakingtimesEditField.Value = value;
        end

        % Callback function
        function WinchSpeedrpmSliderValueChanging(app, event)
            changingValue = event.Value;
            set_param([bdroot,'/ConstantSP2'],'Value',num2str(changingValue))
            app.Winch2SpeedrpmEditField.Value = changingValue;
        end

        % Callback function
        function WinchSpeedrpmEditField_2ValueChanged(app, event)
            value = app.Winch2SpeedrpmEditField.Value;
            set_param([bdroot,'/ConstantSP2'],'Value',num2str(value))
            app.WinchSpeedrpmSlider.Value = value;
        end

        % Callback function
        function WaveAmplituderpmEditFieldValueChanged(app, event)
            value = app.WaveAmplituderpmEditField.Value;
            set_param([bdroot,'/SineWave2'],'Amplitude',num2str(value))
            set_param([bdroot,'/TriGain2'],'Gain',num2str(value))
        end

        % Callback function
        function WavePeriodsEditField_2ValueChanged(app, event)
            value = app.Winch2PeriodsEditField.Value;
            set_param([bdroot,'/SineWave2'],'Frequency',num2str(2*pi/value))
            set_param([bdroot,'/TriangleWave2'],'Freq',num2str(1/value))
        end

        % Callback function
        function SpoolingProfileButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.SpoolingProfileButtonGroup_2.SelectedObject;
            
            if strcmp(selectedButton.Text,'Constant') % Zero sine and triangle
                set_param([bdroot,'/SineOnOff2'],'Gain','0')
                set_param([bdroot,'/TriOnOff2'],'Gain','0')
                
            elseif strcmp(selectedButton.Text,'Sine') % Zero triangle
                set_param([bdroot,'/SineOnOff2'],'Gain','1')
                set_param([bdroot,'/TriOnOff2'],'Gain','0')
                
            else                                      % Zero sine
                set_param([bdroot,'/SineOnOff2'],'Gain','0')
                set_param([bdroot,'/TriOnOff2'],'Gain','1')
                
            end
        end

        % Callback function
        function SpoolDirectionSwitch_2ValueChanged(app, event)
            value = app.SpoolDirectionSwitch_2.Value;
            
            if strcmp(value,'In')
                value = 0;
            else
                value = 1;
            end
            
            set_param([bdroot,'/Winch2Direction'],'Value',num2str(value))
        end

        % Value changed function: DualWinchModeSwitch
        function DualWinchModeSwitchValueChanged(app, event)
            value = app.DualWinchModeSwitch.Value;
            if strcmp(value,'Coupled (W2 = W1 + W2)')
                set_param([bdroot,'/SyncSwitch'],'Value','1')
                set_param([bdroot,'/CouplingOnOff'],'Gain','1')
            else
                set_param([bdroot,'/SyncSwitch'],'Value','0')
                set_param([bdroot,'/CouplingOnOff'],'Gain','0')
            end
        end

        % Value changed function: StartWinch2Button
        function StartWinch2ButtonValueChanged(app, event)
            value = app.StartWinch2Button.Value;
            set_param([bdroot,'/Winch2Power'],'Value',num2str(value))
            app.Lamp_3.Color = [1-value,value,0];
        end

        % Selection changed function: SpoolingProfileButtonGroup_2
        function SpoolingProfileButtonGroup_2SelectionChanged2(app, event)
            selectedButton = app.SpoolingProfileButtonGroup_2.SelectedObject;
            
            if strcmp(selectedButton.Text,'Constant') % Zero sine and triangle
                set_param([bdroot,'/SineOnOff2'],'Gain','0')
                set_param([bdroot,'/TriOnOff2'],'Gain','0')
                
            elseif strcmp(selectedButton.Text,'Sine') % Zero triangle
                set_param([bdroot,'/SineOnOff2'],'Gain','1')
                set_param([bdroot,'/TriOnOff2'],'Gain','0')
                
            else                                      % Zero sine
                set_param([bdroot,'/SineOnOff2'],'Gain','0')
                set_param([bdroot,'/TriOnOff2'],'Gain','1')
                
            end   
            
        end

        % Value changed function: Winch2SpeedrpmEditField
        function Winch2SpeedrpmEditFieldValueChanged(app, event)
            value = app.Winch2SpeedrpmEditField.Value;
            set_param([bdroot,'/ConstantSP2'],'Value',num2str(value))
            app.Winch2SpeedrpmSlider.Value = value;
        end

        % Value changed function: Winch2AmplituderpmEditField
        function Winch2AmplituderpmEditFieldValueChanged(app, event)
            value = app.Winch2AmplituderpmEditField.Value;
            set_param([bdroot,'/SineWave2'],'Amplitude',num2str(value))
            set_param([bdroot,'/TriGain2'],'Gain',num2str(value))
        end

        % Value changed function: Winch2PeriodsEditField
        function Winch2PeriodsEditFieldValueChanged(app, event)
            value = app.Winch2PeriodsEditField.Value;
            set_param([bdroot,'/SineWave2'],'Frequency',num2str(2*pi/value))
            set_param([bdroot,'/TriangleWave2'],'Freq',num2str(1/value))
        end

        % Value changed function: Winch2SpeedrpmSlider
        function Winch2SpeedrpmSliderValueChanged(app, event)
            changingValue = event.Value;
            set_param([bdroot,'/ConstantSP2'],'Value',num2str(changingValue))
            app.Winch2SpeedrpmEditField.Value = changingValue;
        end

        % Value changed function: LASCalibrationButton
        function LASCalibrationButtonValueChanged(app, event)
            value = app.LASCalibrationButton.Value;
            evalin('base', 'CalibrateLAS')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.WinchControlTab = uitab(app.TabGroup);
            app.WinchControlTab.Tooltip = {''};
            app.WinchControlTab.Title = 'Winch Control';
            app.Winch1SpeedrpmSliderLabel = uilabel(app.WinchControlTab);
            app.Winch1SpeedrpmSliderLabel.Text = 'Winch 1 Speed (rpm)';
            app.Winch1SpeedrpmSlider = uislider(app.WinchControlTab);
            app.Winch1SpeedrpmSlider.Limits = [0 120];
            app.Winch1SpeedrpmSlider.MajorTicks = [0 20 40 60 80 100 120];
            app.Winch1SpeedrpmSlider.ValueChangingFcn = createCallbackFcn(app, @Winch1SpeedrpmSliderValueChanging, true);
            app.Winch1SpeedrpmEditFieldLabel = uilabel(app.WinchControlTab);
            app.Winch1SpeedrpmEditFieldLabel.Text = 'Winch 1 Speed (rpm)';
            app.Winch1SpeedrpmEditField = uieditfield(app.WinchControlTab, 'numeric');
            app.Winch1SpeedrpmEditField.Limits = [0 120];
            app.Winch1SpeedrpmEditField.ValueChangedFcn = createCallbackFcn(app, @Winch1SpeedrpmEditFieldValueChanged, true);
            app.EngageWinchesButton = uibutton(app.WinchControlTab, 'state');
            app.EngageWinchesButton.ValueChangedFcn = createCallbackFcn(app, @EngageWinchesButtonValueChanged, true);
            app.EngageWinchesButton.Text = 'Engage Winches';
            app.StartWinch12Button = uibutton(app.WinchControlTab, 'state');
            app.StartWinch12Button.ValueChangedFcn = createCallbackFcn(app, @StartWinch12ButtonValueChanged, true);
            app.StartWinch12Button.Text = 'Start Winch 1 (+2)';
            app.SpoolingDirectionSwitchLabel = uilabel(app.WinchControlTab);
            app.SpoolingDirectionSwitchLabel.Text = 'Spooling Direction';
            app.SpoolingDirectionSwitch = uiswitch(app.WinchControlTab, 'slider');
            app.SpoolingDirectionSwitch.Items = {'In', 'Out'};
            app.SpoolingDirectionSwitch.ValueChangedFcn = createCallbackFcn(app, @SpoolingDirectionSwitchValueChanged, true);
            app.SpoolingDirectionSwitch.Value = 'Out';
            app.Winch1AmplituderpmEditFieldLabel = uilabel(app.WinchControlTab);
            app.Winch1AmplituderpmEditFieldLabel.Text = 'Winch 1 Amplitude (rpm)';
            app.Winch1AmplituderpmEditField = uieditfield(app.WinchControlTab, 'numeric');
            app.Winch1AmplituderpmEditField.ValueChangedFcn = createCallbackFcn(app, @Winch1AmplituderpmEditFieldValueChanged, true);
            app.Winch1PeriodsEditFieldLabel = uilabel(app.WinchControlTab);
            app.Winch1PeriodsEditFieldLabel.Text = 'Winch 1 Period (s)';
            app.Winch1PeriodsEditField = uieditfield(app.WinchControlTab, 'numeric');
            app.Winch1PeriodsEditField.Limits = [0.1 Inf];
            app.Winch1PeriodsEditField.ValueChangedFcn = createCallbackFcn(app, @Winch1PeriodsEditFieldValueChanged, true);
            app.Winch1PeriodsEditField.Value = 1;
            app.BrakingtimesEditFieldLabel = uilabel(app.WinchControlTab);
            app.BrakingtimesEditFieldLabel.Text = 'Braking time (s)';
            app.BrakingtimesEditField = uieditfield(app.WinchControlTab, 'numeric');
            app.BrakingtimesEditField.Limits = [0.02 Inf];
            app.BrakingtimesEditField.ValueChangedFcn = createCallbackFcn(app, @BrakingtimesEditFieldValueChanged, true);
            app.BrakingtimesEditField.Value = 1;
            app.SpoolingProfileButtonGroup = uibuttongroup(app.WinchControlTab);
            app.SpoolingProfileButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SpoolingProfileButtonGroupSelectionChanged, true);
            app.SpoolingProfileButtonGroup.Title = 'Spooling Profile';
            app.ConstantButton = uitogglebutton(app.SpoolingProfileButtonGroup);
            app.ConstantButton.Text = 'Constant';
            app.ConstantButton.Value = true;
            app.SineButton = uitogglebutton(app.SpoolingProfileButtonGroup);
            app.SineButton.Text = 'Sine';
            app.TriangleButton = uitogglebutton(app.SpoolingProfileButtonGroup);
            app.TriangleButton.Text = 'Triangle';
            app.Winch2SpeedrpmSliderLabel = uilabel(app.WinchControlTab);
            app.Winch2SpeedrpmSliderLabel.Text = 'Winch 2 Speed (rpm)';
            app.Winch2SpeedrpmSlider = uislider(app.WinchControlTab);
            app.Winch2SpeedrpmSlider.Limits = [0 120];
            app.Winch2SpeedrpmSlider.MajorTicks = [0 20 40 60 80 100 120];
            app.Winch2SpeedrpmSlider.ValueChangedFcn = createCallbackFcn(app, @Winch2SpeedrpmSliderValueChanged, true);
            app.SpoolingProfileButtonGroup_2 = uibuttongroup(app.WinchControlTab);
            app.SpoolingProfileButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @SpoolingProfileButtonGroup_2SelectionChanged2, true);
            app.SpoolingProfileButtonGroup_2.Title = 'Spooling Profile';
            app.ConstantButton_2 = uitogglebutton(app.SpoolingProfileButtonGroup_2);
            app.ConstantButton_2.Text = 'Constant';
            app.ConstantButton_2.Value = true;
            app.SineButton_2 = uitogglebutton(app.SpoolingProfileButtonGroup_2);
            app.SineButton_2.Text = 'Sine';
            app.TriangleButton_2 = uitogglebutton(app.SpoolingProfileButtonGroup_2);
            app.TriangleButton_2.Text = 'Triangle';
            app.Winch2SpeedrpmEditFieldLabel = uilabel(app.WinchControlTab);
            app.Winch2SpeedrpmEditFieldLabel.Text = 'Winch 2 Speed (rpm)';
            app.Winch2SpeedrpmEditField = uieditfield(app.WinchControlTab, 'numeric');
            app.Winch2SpeedrpmEditField.Limits = [0 120];
            app.Winch2SpeedrpmEditField.ValueChangedFcn = createCallbackFcn(app, @Winch2SpeedrpmEditFieldValueChanged, true);
            app.Winch2AmplituderpmEditFieldLabel = uilabel(app.WinchControlTab);
            app.Winch2AmplituderpmEditFieldLabel.Text = 'Winch 2 Amplitude (rpm)';
            app.Winch2AmplituderpmEditField = uieditfield(app.WinchControlTab, 'numeric');
            app.Winch2AmplituderpmEditField.ValueChangedFcn = createCallbackFcn(app, @Winch2AmplituderpmEditFieldValueChanged, true);
            app.Winch2PeriodsEditFieldLabel = uilabel(app.WinchControlTab);
            app.Winch2PeriodsEditFieldLabel.Text = 'Winch 2 Period (s)';
            app.Winch2PeriodsEditField = uieditfield(app.WinchControlTab, 'numeric');
            app.Winch2PeriodsEditField.Limits = [0.1 Inf];
            app.Winch2PeriodsEditField.ValueChangedFcn = createCallbackFcn(app, @Winch2PeriodsEditFieldValueChanged, true);
            app.Winch2PeriodsEditField.Value = 1;
            app.DualWinchModeSwitch = uiswitch(app.WinchControlTab, 'slider');
            app.DualWinchModeSwitch.Items = {'Coupled (W2 = W1 + W2)', 'Uncoupled'};
            app.DualWinchModeSwitch.ValueChangedFcn = createCallbackFcn(app, @DualWinchModeSwitchValueChanged, true);
            app.DualWinchModeSwitch.Value = 'Uncoupled';
            app.Winch1Label = uilabel(app.WinchControlTab);
            app.Winch1Label.Text = 'Winch 1';
            app.Winch2Label = uilabel(app.WinchControlTab);
            app.Winch2Label.Text = 'Winch 2';
            app.StartWinch2Button = uibutton(app.WinchControlTab, 'state');
            app.StartWinch2Button.ValueChangedFcn = createCallbackFcn(app, @StartWinch2ButtonValueChanged, true);
            app.StartWinch2Button.Text = 'Start Winch 2';
            app.Lamp = uilamp(app.WinchControlTab);
            app.Lamp_2 = uilamp(app.WinchControlTab);
            app.Lamp_3 = uilamp(app.WinchControlTab);
            app.SettingsforbothwinchesLabel = uilabel(app.WinchControlTab);
            app.SettingsforbothwinchesLabel.Text = 'Settings (for both winches)';
            app.TestStartStopLabel = uilabel(app.WinchControlTab);
            app.TestStartStopLabel.Text = 'Test Start/Stop';
            app.CalibrationLabel = uilabel(app.WinchControlTab);
            app.CalibrationLabel.Text = 'Calibration';
            app.LASCalibrationButton = uibutton(app.WinchControlTab, 'state');
            app.LASCalibrationButton.ValueChangedFcn = createCallbackFcn(app, @LASCalibrationButtonValueChanged, true);
            app.LASCalibrationButton.Text = 'LAS Calibration';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appforPool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end